classdef application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Numberofteethanywherebetween12to300EditFieldLabel  matlab.ui.control.Label
        Numofteeth1                     matlab.ui.control.NumericEditField
        Gear1Label                      matlab.ui.control.Label
        Gear2Label                      matlab.ui.control.Label
        Numberofteethanywherebetween12to300EditField_2Label  matlab.ui.control.Label
        Numofteeth2                     matlab.ui.control.NumericEditField
        PinionGearDesignerLabel         matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        MaterialDropDownLabel           matlab.ui.control.Label
        MaterialDropDown                matlab.ui.control.DropDown
        MaterialDropDown_2Label         matlab.ui.control.Label
        MaterialDropDown_2              matlab.ui.control.DropDown
        ToothFormDropDownLabel          matlab.ui.control.Label
        ToothFormDropDown               matlab.ui.control.DropDown
        PowerRequiredinkWEditFieldLabel  matlab.ui.control.Label
        PowerRequired                   matlab.ui.control.EditField
        IncludeDynamicAnalysisCheckBox  matlab.ui.control.CheckBox
        ResetButton                     matlab.ui.control.Button
        SelectDriverButtonGroup         matlab.ui.container.ButtonGroup
        Gear1Button                     matlab.ui.control.RadioButton
        Gear2Button                     matlab.ui.control.RadioButton
        RevolutionPerMinuteEditFieldLabel  matlab.ui.control.Label
        RevolutionPerMinuteEditField    matlab.ui.control.NumericEditField
        RevolutionPerMinuteEditField_2Label  matlab.ui.control.Label
        RevolutionPerMinuteEditField_2  matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            numofteeth1 = app.Numofteeth1.Value;
            numofteeth2 = app.Numofteeth2.Value;
            profile = app.ToothFormDropDown.Value;
            material1 = app.MaterialDropDown.Value;
            material2 = app.MaterialDropDown.Value;
            power = app.PowerRequired.Value;
            
            if material1 == material2
                
                if numofteeth1 > numofteeth2
                    y = LewisFactor(numofteeth1, profile);
                elseif numofteeth2 > numofteeth1
                    y = LewisFactor(numofteeth2, profile);
                end
                
            else
                  y1 = LewisFactor(numofteeth1, profile);
                  y2 = LewisFactor(numofteeth2, profile);
                  strength1 = S_o(material1);
                  strength2 = S_o(material2);
                  
                  if strength1*y1 > strength2*y2
                      y = y1;
                  elseif strength2*y2 > strength1*y1
                      y = y2;
                  end
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Numberofteethanywherebetween12to300EditFieldLabel = uilabel(app.UIFigure);
            app.Numberofteethanywherebetween12to300EditFieldLabel.Text = 'Number of teeth (anywhere between 12 to 300)';
            app.Numofteeth1 = uieditfield(app.UIFigure, 'numeric');
            app.Numofteeth1.Limits = [12 300];
            app.Numofteeth1.RoundFractionalValues = 'on';
            app.Numofteeth1.Value = 12;
            app.Gear1Label = uilabel(app.UIFigure);
            app.Gear1Label.Text = 'Gear 1';
            app.Gear2Label = uilabel(app.UIFigure);
            app.Gear2Label.Text = 'Gear 2';
            app.Numberofteethanywherebetween12to300EditField_2Label = uilabel(app.UIFigure);
            app.Numberofteethanywherebetween12to300EditField_2Label.Text = 'Number of teeth (anywhere between 12 to 300)';
            app.Numofteeth2 = uieditfield(app.UIFigure, 'numeric');
            app.Numofteeth2.Limits = [12 300];
            app.Numofteeth2.RoundFractionalValues = 'on';
            app.Numofteeth2.Value = 12;
            app.PinionGearDesignerLabel = uilabel(app.UIFigure);
            app.PinionGearDesignerLabel.Text = 'Pinion Gear Designer';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MaterialDropDownLabel = uilabel(app.UIFigure);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.UIFigure);
            app.MaterialDropDown.Items = {'Steel', 'Cast Iron', 'Bronze', ''};
            app.MaterialDropDown.Value = 'Steel';
            app.MaterialDropDown_2Label = uilabel(app.UIFigure);
            app.MaterialDropDown_2Label.Text = 'Material';
            app.MaterialDropDown_2 = uidropdown(app.UIFigure);
            app.MaterialDropDown_2.Items = {'Steel', 'Cast Iron', 'Bronze', ''};
            app.MaterialDropDown_2.Value = 'Steel';
            app.ToothFormDropDownLabel = uilabel(app.UIFigure);
            app.ToothFormDropDownLabel.Text = 'Tooth Form';
            app.ToothFormDropDown = uidropdown(app.UIFigure);
            app.ToothFormDropDown.Items = {'Involute 14.5', 'Full Depth 20', 'Stub 20'};
            app.ToothFormDropDown.Value = 'Involute 14.5';
            app.PowerRequiredinkWEditFieldLabel = uilabel(app.UIFigure);
            app.PowerRequiredinkWEditFieldLabel.Text = 'Power Required (in kW)';
            app.PowerRequired = uieditfield(app.UIFigure, 'text');
            app.IncludeDynamicAnalysisCheckBox = uicheckbox(app.UIFigure);
            app.IncludeDynamicAnalysisCheckBox.Text = 'Include Dynamic Analysis';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.Text = 'Reset';
            app.SelectDriverButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectDriverButtonGroup.Title = 'Select Driver';
            app.Gear1Button = uiradiobutton(app.SelectDriverButtonGroup);
            app.Gear1Button.Text = 'Gear 1';
            app.Gear1Button.Value = true;
            app.Gear2Button = uiradiobutton(app.SelectDriverButtonGroup);
            app.Gear2Button.Text = 'Gear 2';
            app.RevolutionPerMinuteEditFieldLabel = uilabel(app.UIFigure);
            app.RevolutionPerMinuteEditFieldLabel.Text = 'Revolution Per Minute';
            app.RevolutionPerMinuteEditField = uieditfield(app.UIFigure, 'numeric');
            app.RevolutionPerMinuteEditField.Limits = [1 Inf];
            app.RevolutionPerMinuteEditField.Value = 1;
            app.RevolutionPerMinuteEditField_2Label = uilabel(app.UIFigure);
            app.RevolutionPerMinuteEditField_2Label.Text = 'Revolution Per Minute';
            app.RevolutionPerMinuteEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.RevolutionPerMinuteEditField_2.Limits = [1 Inf];
            app.RevolutionPerMinuteEditField_2.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end